classdef Calibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationFigure              matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SaveCalibrationDataMenu        matlab.ui.container.Menu
        LoadCalibrationDataMenu        matlab.ui.container.Menu
        StimulusPanel                  matlab.ui.container.Panel
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        StimulusInfoButton             matlab.ui.control.Button
        ModifyButton                   matlab.ui.control.Button
        HardwarePanel                  matlab.ui.container.Panel
        AudioDeviceDropDownLabel       matlab.ui.control.Label
        AudioDeviceDropDown            matlab.ui.control.DropDown
        SamplingRateDropDownLabel      matlab.ui.control.Label
        SamplingRateDropDown           matlab.ui.control.DropDown
        HardwareInfoButton             matlab.ui.control.Button
        CalibrationPanel               matlab.ui.container.Panel
        LocatePlotsButton              matlab.ui.control.Button
        RunCalibrationSwitch           matlab.ui.control.RockerSwitch
        NoteTextAreaLabel              matlab.ui.control.Label
        NoteTextArea                   matlab.ui.control.TextArea
        NormLeveldBEditFieldLabel      matlab.ui.control.Label
        NormLeveldBEditField           matlab.ui.control.NumericEditField
        CalibrationStateLamp           matlab.ui.control.Lamp
        CalibrationInfoPanel           matlab.ui.control.Button
        ReferencePanel                 matlab.ui.container.Panel
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        FrequencyHzEditField           matlab.ui.control.NumericEditField
        SoundLeveldBSPLEditFieldLabel  matlab.ui.control.Label
        SoundLeveldBSPLEditField       matlab.ui.control.NumericEditField
        MeasuredVoltagemVEditFieldLabel  matlab.ui.control.Label
        MeasuredVoltagemVEditField     matlab.ui.control.NumericEditField
        SampleButton                   matlab.ui.control.Button
        ReferenceLamp                  matlab.ui.control.Lamp
        ReferenceInfoButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        C               abr.AcousticCalibration = abr.AcousticCalibration;
        playRecObj      audioPlayerRecorder = audioPlayerRecorder;
        STATE           {mustBeMember(STATE,{'setup','idle','prerun','running','postrun','error','usercancelled'})} = 'idle';
        frameSize       (1,1) double = 1024;
        lastError   % me
        
        
        thisOne
        
        % params
        sweepOnsets (1,:) double {mustBeNonnegative,mustBeFinite} % sec
        sweepRate (1,1) double {mustBePositive,mustBeFinite} = 2; % Hz
        sweepDuration  (1,:) double {mustBePositive,mustBeFinite} = 0.25; % sec
        
        % tone params
        F1  (1,1) double {mustBePositive,mustBeFinite} = 500;   % First frequency Hz
        F2  (1,1) double {mustBePositive,mustBeFinite} = 86400; % Second frequency Hz (should be set to ~.45*Fs)
        Fn  (1,1) double {mustBePositive,mustBeInteger} = 25;  % number of samples between F1 and F2 (lin)
        
        % noise params
        FHp (1,:) double {mustBePositive,mustBeFinite} = 500;   % High-Pass Frequency Corner (Hz)
        FLp (1,:) double {mustBePositive,mustBeFinite} = 64000; % Low-Pass Frequency Corner (Hz)
        
        sortProperty (1,:) char
        
        ResponseFigure
        ReferenceFigure
    end
    
    methods
        
        function set.STATE(app,newState)
            
            h = app.thisOne;
            
            h.Color = [0.8 0.8 0.8];
            
            switch newState
                case 'setup'
                    h.Tooltip = 'Starting';
                    h.Color = [1 1 0];
                    
                case 'idle'
                    h.Tooltip = '';
                    
                case 'prerun'
                    h.Tooltip = 'Prepping';
                    h.Color = [1 1 0];
                    
                case 'running'
                    h.Tooltip = 'Running';
                    h.Color = [0.2 1 0.2];
                    
                case 'postrun'
                    h.Tooltip = 'Done';
                    app.RunCalibrationSwitch.Value = 'Idle';
                    
                case 'error'
                    %                     h.Tooltip = app.lastError.message;
                    h.Color = [1 0 0];
                    app.RunCalibrationSwitch.Value = 'Idle';
                    
                case 'usercancelled'
                    h.Tooltip = 'Cancelled';
                    app.RunCalibrationSwitch.Value = 'Idle';
            end
            
            drawnow
        end


        function f = get.ResponseFigure(app)
            f = findobj('type','figure','-and','name','Response');
            if isempty(f)
                f = figure('name','Response','IntegerHandle','off', ...
                    'Color','w','Position',[300 60 860 590]);
            end
            figure(f);
        end
    
        function f = get.ReferenceFigure(app)
            f = findobj('type','figure','-and','name','Reference');
            if isempty(f)
                f = figure('name','Response','IntegerHandle','off', ...
                    'Color','w','Position',[200 150 500 300]);
            end
            figure(f);
        end
        
    end
    
    methods (Access = private)
        
        function gather_parameters(app)
            app.C.Device        = app.AudioDeviceDropDown.Value;
            app.C.SampleRate    = str2double(app.SamplingRateDropDown.Value);
            
            app.C.ReferenceFreq = app.FrequencyHzEditField.Value;
            app.C.ReferenceSPL  = app.SoundLeveldBSPLEditField.Value;
            app.C.ReferenceV    = app.MeasuredVoltagemVEditField.Value./1000;
            
            app.C.Note = app.NoteTextArea.Value;
        end


        function setup_playrec(app)
            app.STATE = 'prerun';
            
            app.gather_parameters;
            
            if ~ismethod(app.playRecObj,'isvalid') || ~isvalid(app.playRecObj)
                app.playRecObj = audioPlayerRecorder;
            end
            release(app.playRecObj);
            
            app.playRecObj.Device     = app.C.Device;
            app.playRecObj.SampleRate = app.C.SampleRate;
            
            app.C.DeviceInfo = app.playRecObj.info;
            
            % reset buffers
            app.C.DAC = Buffer(app.C.SampleRate);
            app.C.ADC = Buffer(app.C.SampleRate);
            
            app.C.DAC.FrameSize = app.frameSize;
            app.C.ADC.FrameSize = app.frameSize;
            
        end


        function trigger_playrec(app)
            bidx = 1:app.C.DAC.FrameSize:app.C.DAC.N+1;
            for i = 1:length(bidx)-1
                idx = bidx(i):bidx(i+1)-1;
                app.C.ADC.Data(idx) = app.playRecObj(app.C.DAC.Data(idx));
            end
        end


        function update_reference_plot(app)
            f = app.ReferenceFigure;
            
            % Time-domain plot
            ax = subplot(211,'Parent',f);
            app.C.ADC.plotSweeps(ax);
            ax.Title.String = sprintf('Reference Tone RMS = %.3f mV RMS',app.C.ADC.RMS.*1000);
            
            % Freq-domain plot
            ax = subplot(212,'Parent',f);
            app.C.ADC.plotFFT(ax);
            ax.XAxis.Exponent = 0;
        end
        
        function update_response_plot(app)
            f = app.ResponseFigure;
            
            % Sound level plot
            ax = subplot(3,2,[1 2],'Parent',f);
            cla(ax);
            
            x = app.C.SIG.dataParams.(app.sortProperty) ./ 1000;
            y = app.C.MeasuredSPL;
            
            lineColors = hsv(app.C.ADC.NumSweeps);
            
            line(ax,x,y,'color',[0 0 0]);
            for i = 1:length(x)
                line(ax,x(i),y(i),'color',lineColors(i,:),'marker','o', ...
                    'markersize',10,'markerfacecolor',lineColors(i,:));
            end
            
            grid(ax,'on');
            ax.XAxis.Label.String = 'frequency (kHz)';
            ax.YAxis.Label.String = 'Sound Level (dB SPL)';
            
            
            % time domain plot
            ax = subplot(3,2,[3 5],'Parent',f);
            cla(ax);
            
            x = app.C.ADC.TimeVector .* 1000;
            y = app.C.SIG.dataParams.(app.sortProperty);
            z = app.C.ADC.SweepData .* 1000;
            
            [~,y] = meshgrid(x,y);
            
            for i = 1:app.C.ADC.NumSweeps
                h(i) = line(ax,x,y(i,:),z(:,i),'color',lineColors(i,:));
            end
            
            ax.XAxis.TickLabelFormat = '%.1f';
            ax.YAxis.TickLabelFormat = '%.1f';
            ax.ZAxis.TickLabelFormat = '%.1f';
            
            ax.XAxis.Label.String = 'time (ms)';
            ax.YAxis.Label.String = app.sortProperty;
            ax.ZAxis.Label.String = 'amplitude (mV)';
            
            ax.YAxis.Exponent = 0;
            
            grid(ax,'on');
            view(ax,3);


            % freq domain plot
            ax = subplot(3,2,[4 6],'Parent',f);
            cla(ax);
            
            warning('off','MATLAB:colon:nonIntegerIndex');
            for i = 1:app.C.ADC.NumSweeps
                Y = z(:,i);
                L = length(Y);
                w = window('hanning',L);
                Y = Y.*w;
                Y = fft(Y);
                P2 = abs(Y/L);
                M = P2(1:L/2+1);
                M(2:end-1) = 2*M(2:end-1);
                M = 20.*log10(M);
                f = app.C.ADC.SampleRate*(0:(L/2))/L;
                line(ax,f./1000,M,'color',lineColors(i,:));
            end
            warning('on','MATLAB:colon:nonIntegerIndex');
            
            grid(ax,'on');
            axis(ax,'tight');
            
            ax.XAxis.Label.String = 'frequency (kHz)';
            ax.YAxis.Label.String = 'Power (dB)';
            
            drawnow
        end
        
        function load_sig(app,SIG)
            
            sigtype = app.TypeDropDown.Value;
            
            if nargin == 2 && ~isempty(SIG) && isa(SIG,['abr.sigdef.sigs.' sigtype])
                app.C.SIG = SIG;
                app.C.SIG.Fs = str2double(app.SamplingRateDropDown.Value);
                app.RunCalibrationSwitch.Enable = 'on';
                return
            end
            
            app.C.SIG = abr.sigdef.sigs.(sigtype);
            app.C.SIG.Fs = str2double(app.SamplingRateDropDown.Value);


            % defaults
            app.C.SIG.soundLevel.Value = app.NormLeveldBEditField.Value; % db
            
            switch app.C.SIG.Type
                case 'Tone'
                    app.F2 = app.C.SIG.Fs*0.45;
                    app.C.SIG.frequency.Value  = sprintf('linspace(%g,%g,%d)',app.F1/1000,app.F2/1000,app.Fn); % kHz
                    app.C.SIG.duration.Value   = 250; % ms
                    app.C.SIG.windowFcn.Value  = 'blackmanharris';
                    app.C.SIG.windowRFTime.Value = 1000.*4./app.F1; % ramp over at least one cycle
                    
                    app.sortProperty = 'frequency';
                    
                case 'Noise'
                    app.C.SIG.HPfreq.Value     = app.FHp/1000;
                    app.C.SIG.LPfreq.Value     = app.FLp/1000;
                    app.C.SIG.duration.Value   = 250; % ms
                    app.C.SIG.windowFcn.Value  = 'blackmanharris';
                    app.C.SIG.windowRFTime.Value = 1000.*2./app.FHp; % ramp over at least one cycle
                    
                    app.sortProperty = 'HPFreq';
                    
                case 'Click'
                    app.C.SIG.duration.Value   = 0.01; % ms
                    
                    app.sortProperty = 'duration';
                    
                case 'File'
                    uiconfirm(app.ScheduleFigure, ...
                        'File calibration not yet implemented.','Calibration', ...
                        'Icon','info');
                    app.RunCalibrationSwitch.Enable = 'off';
                    return
            end
            
            w = app.C.SIG.windowRFTime.realValue;
            d = app.C.SIG.duration.realValue;
            app.C.CalcWindow = [w d-w];
            
            app.RunCalibrationSwitch.Enable = 'on';
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STATE = 'setup';
            
            app.playRecObj = audioPlayerRecorder;
            
            devices = app.playRecObj.getAudioDevices;
            lastused = getpref('AcousticCalibration','audioDevice',devices{1});
            ind = ismember(devices,lastused);
            if any(ind)
                lastused = devices{ind};
            else
                if ispc
                    ind = contains(devices,'ASIO','IgnoreCase',true);
                elseif isunix
                    ind = contains(devices,'ALSA','IgnoreCase',true);
                elseif ismac
                    ind = contains(devices,'CoreAudio','IgnoreCase',true);
                else
                    ind = false;
                end
                
                if any(ind)
                    lastused = devices{ind};
                else
                    lastused = devices{1};
                end
            end
            app.AudioDeviceDropDown.Items = devices;
            app.AudioDeviceDropDown.Value = lastused;
            
            lastused = getpref('AcousticCalibration','samplingRate','44100');
            app.SamplingRateDropDown.Value = lastused;
            
            app.STATE = 'idle';
        end

        % Close request function: CalibrationFigure
        function CalibrationFigureCloseRequest(app, event)
            if isequal(app.STATE,'running')
                msgbox(sprintf('I''m sorry Dave, I can''t do that.\n\nPlease stop calibration before exiting.'),'Calibration','warn','modal');
                return
            end
            delete(app)
            
        end

        % Value changed function: RunCalibrationSwitch
        function RunCalibrationSwitchValueChanged(app, event)
            app.thisOne = app.CalibrationStateLamp;
            value = app.RunCalibrationSwitch.Value;
            
            switch value
                case 'Run'
                    % initialize calibration voltage
                    app.C.StimulusV = repmat(0.1,app.C.DAC.NumSweeps,1);
                    
                    for i = 1:2
                        app.setup_playrec;
                        
                        % generate stimulus from SIG obj
                        app.C.SIG.Fs = app.C.DAC.SampleRate;
                        app.C.SIG = app.C.SIG.update;
                        
                        app.C.SIG = sort(app.C.SIG,app.sortProperty,'ascend');
                        
                        if i == 2
                            % multiply full signal by calibrated value
                            stimData = cellfun(@times,num2cell(app.C.StimulusV),app.C.SIG.data,'uni',0);
                        else
                            stimData = app.C.SIG.data;
                        end
                        
                        sweepInterval = 1./app.sweepRate;
                        sweepIntervalSamps = app.C.SIG.Fs*sweepInterval;
                        app.sweepOnsets = 0:sweepInterval:sweepInterval.*(app.C.SIG.signalCount-1);
                        
                        
                        % initialize Buffers
                        data = cell2mat(stimData'); % assuming all the same duration
                        data(sweepIntervalSamps,end) = 0;
                        app.C.DAC.Data = data(:); clear data
                        app.C.DAC.SweepLength = app.C.SIG.N;
                        app.C.DAC.SweepOnsets = round(app.C.DAC.SampleRate.*app.sweepOnsets)+1; % single acquisition
                        
                        app.C.ADC.preallocate(app.C.DAC.N);
                        app.C.ADC.SweepLength = app.C.SIG.N;
                        app.C.ADC.SweepOnsets = app.C.DAC.SweepOnsets;
                        
                        
                        app.STATE = 'running';
                        
                        app.trigger_playrec;
                        
                        
                        % plot time & freq representations of ADC
                        app.update_response_plot;
                        
                        
                        if i == 1
                            app.C.NormalizedV = app.C.computeAdjV;
                            app.C.StimulusV   = app.C.NormalizedV;
                        else
                            v = app.C.computeAdjV;
                        end
                    end
                    
                    app.STATE = 'postrun';
                    
                    %  app.SaveCalibrationDataMenuSelected;
                    
                case 'Idle'
                    app.STATE = 'usercancelled';
            end
            
            app.RunCalibrationSwitch.Value = 'Idle';
        end

        % Value changed function: AudioDeviceDropDown
        function AudioDeviceDropDownValueChanged(app, event)
            value = app.AudioDeviceDropDown.Value;
            setpref('AcousticCalibration','audioDevice',value);
        end

        % Value changed function: SamplingRateDropDown
        function SamplingRateDropDownValueChanged(app, event)
            value = app.SamplingRateDropDown.Value;
            setpref('AcousticCalibration','samplingRate',value);
        end

        % Button pushed function: SampleButton
        function SampleButtonPushed(app, event)
            app.thisOne = app.ReferenceLamp;
            try                
                app.setup_playrec;
                
                % initialize Buffers
                app.C.DAC.Data = zeros(app.C.DAC.SampleRate,1);
                app.C.DAC.SweepLength = app.C.DAC.N; % same in this case
                app.C.DAC.SweepOnsets = 1; % single acquisition
                
                app.C.ADC.Data = zeros(app.C.ADC.SampleRate,1);
                app.C.ADC.SweepLength = app.C.ADC.N; % same in this case
                app.C.ADC.SweepOnsets = 1; % single acquisition
                
                app.STATE = 'running';
                
                app.trigger_playrec;
                
                % plot time & freq representations of ADC
                app.update_reference_plot;
                
                % Measure signal power and update field
                app.MeasuredVoltagemVEditField.Value = app.C.ADC.RMS.*1000;
                
                app.STATE = 'postrun';
                
            catch me
                app.STATE = 'error';
                app.lastError = me;
                rethrow(me); % handle error message
            end
            
        end

        % Button pushed function: ModifyButton
        function ModifyButtonPushed(app, event)
            app.RunCalibrationSwitch.Enable = 'off'; drawnow
            
            app.load_sig(app.C.SIG);
            
            S = ScheduleDesign(app.C.SIG,1);
            
            waitfor(S.CompileButton,'UserData'); % Must hit compile button to continue
            
            if ~isvalid(S), return; end
            
            app.C.SIG = S.SIG;
            
            close(S.ScheduleDesignFigure);
            
            app.RunCalibrationSwitch.Enable = 'on';
        end

        % Menu selected function: SaveCalibrationDataMenu
        function SaveCalibrationDataMenuSelected(app, event)
            dfltpn = getpref('AcousticCalibration','dataPath',cd);
            [pn,fn] = uiputfile({'*.cal','Calibration (*.cal)'}, ...
                'Save Calibration Data',dfltpn);
            
            if isequal(fn,0), return; end
            
            ffn = fullfile(pn,fn);
            
            CalibrationData = app.C;
            
            CalibrationData.Timestamp = datestr(now);
            
            save(ffn,'CalibrationData','-mat');
            
            setpref('AcousticCalibration','dataPath',pn);
        end

        % Menu selected function: LoadCalibrationDataMenu
        function LoadCalibrationDataMenuSelected(app, event)
            dfltpn = getpref('AcousticCalibration','dataPath',cd);
            [pn,fn] = uigetfile({'*.cal','Calibration (*.cal)'}, ...
                'Load Calibration Data', ...
                dfltpn,'MultiSelect','off');
            
            if isequal(fn,0), return; end
            
            ffn = fullfile(pn,fn);
            
            fprintf('Loading %s\n',ffn)
            
            load(ffn,'CalibrationData','-mat');
            
            app.C = CalibrationData;
            app.C.SIG = CalibrationData.SIG;
            
            fprintf('\tCalibration from: %s\n',app.C.Timestamp);
            
            setpref('AcousticCalibration','dataPath',pn);
            
        end

        % Button pushed function: ReferenceInfoButton
        function ReferenceInfoButtonPushed(app, event)
            msgbox(sprintf(['\tUse a piston phone or electronic speaker at at a known sound level to estimate the sensitivity of your microphone and amplifier.  Enter the frequency of the reference tone ' ...
                'it''s known sound level (dB SPL) and click "Sample".\n\nInspect the resulting time- and frequency-domain plots for a clean sinusoid at the specified frequency.']),'Reference Help','help','modal');
            
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            app.load_sig;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationFigure = uifigure;
            app.CalibrationFigure.Name = 'UI Figure';
            app.CalibrationFigure.CloseRequestFcn = createCallbackFcn(app, @CalibrationFigureCloseRequest, true);
            app.FileMenu = uimenu(app.CalibrationFigure);
            app.FileMenu.Text = 'File';
            app.SaveCalibrationDataMenu = uimenu(app.FileMenu);
            app.SaveCalibrationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCalibrationDataMenuSelected, true);
            app.SaveCalibrationDataMenu.Accelerator = 'S';
            app.SaveCalibrationDataMenu.Text = 'Save Calibration Data';
            app.LoadCalibrationDataMenu = uimenu(app.FileMenu);
            app.LoadCalibrationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadCalibrationDataMenuSelected, true);
            app.LoadCalibrationDataMenu.Accelerator = 'L';
            app.LoadCalibrationDataMenu.Text = 'Load Calibration Data';
            app.StimulusPanel = uipanel(app.CalibrationFigure);
            app.StimulusPanel.Title = 'Stimulus';
            app.TypeDropDownLabel = uilabel(app.StimulusPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.StimulusPanel);
            app.TypeDropDown.Items = {'Tone', 'Noise', 'Click', 'File'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Tone';
            app.StimulusInfoButton = uibutton(app.StimulusPanel, 'push');
            app.StimulusInfoButton.Icon = 'helpicon.gif';
            app.StimulusInfoButton.Text = '';
            app.ModifyButton = uibutton(app.StimulusPanel, 'push');
            app.ModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonPushed, true);
            app.ModifyButton.Text = 'Modify';
            app.HardwarePanel = uipanel(app.CalibrationFigure);
            app.HardwarePanel.Title = 'Hardware';
            app.AudioDeviceDropDownLabel = uilabel(app.HardwarePanel);
            app.AudioDeviceDropDownLabel.Text = 'Audio Device';
            app.AudioDeviceDropDown = uidropdown(app.HardwarePanel);
            app.AudioDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioDeviceDropDownValueChanged, true);
            app.SamplingRateDropDownLabel = uilabel(app.HardwarePanel);
            app.SamplingRateDropDownLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown = uidropdown(app.HardwarePanel);
            app.SamplingRateDropDown.Items = {'44100 Hz', '48000 Hz', '96000 Hz', '176400 Hz', '192000 Hz'};
            app.SamplingRateDropDown.ItemsData = {'44100', '48000', '96000', '176400', '192000'};
            app.SamplingRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingRateDropDownValueChanged, true);
            app.SamplingRateDropDown.Tooltip = {'Not all sampling rates  may be supported by your sound card.'};
            app.SamplingRateDropDown.Value = '44100';
            app.HardwareInfoButton = uibutton(app.HardwarePanel, 'push');
            app.HardwareInfoButton.Icon = 'helpicon.gif';
            app.HardwareInfoButton.Text = '';
            app.CalibrationPanel = uipanel(app.CalibrationFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.LocatePlotsButton = uibutton(app.CalibrationPanel, 'push');
            app.LocatePlotsButton.Text = 'Locate Plots';
            app.RunCalibrationSwitch = uiswitch(app.CalibrationPanel, 'rocker');
            app.RunCalibrationSwitch.Items = {'Idle', 'Run'};
            app.RunCalibrationSwitch.ValueChangedFcn = createCallbackFcn(app, @RunCalibrationSwitchValueChanged, true);
            app.RunCalibrationSwitch.Enable = 'off';
            app.RunCalibrationSwitch.Tooltip = {'Must sample a reference tone and define a stimulus before calibration.'};
            app.RunCalibrationSwitch.Value = 'Idle';
            app.NoteTextAreaLabel = uilabel(app.CalibrationPanel);
            app.NoteTextAreaLabel.Text = 'Note';
            app.NoteTextArea = uitextarea(app.CalibrationPanel);
            app.NormLeveldBEditFieldLabel = uilabel(app.CalibrationPanel);
            app.NormLeveldBEditFieldLabel.Text = 'Norm Level (dB)';
            app.NormLeveldBEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.NormLeveldBEditField.Limits = [-100 110];
            app.NormLeveldBEditField.Value = 80;
            app.CalibrationStateLamp = uilamp(app.CalibrationPanel);
            app.CalibrationInfoPanel = uibutton(app.CalibrationPanel, 'push');
            app.CalibrationInfoPanel.Icon = 'helpicon.gif';
            app.CalibrationInfoPanel.Text = '';
            app.ReferencePanel = uipanel(app.CalibrationFigure);
            app.ReferencePanel.Tooltip = {'Use a piston phone or electronic speaker at at a known sound level to estimate the sensitivity of your microphone and amplifier.  Enter the frequency of the reference tone'; ' it''s known sound level (dB SPL) and click "Sample".  Inspect the resulting time- and frequency-domain plots for a clean sinusoid at the specified frequency.s'};
            app.ReferencePanel.Title = 'Reference';
            app.FrequencyHzEditFieldLabel = uilabel(app.ReferencePanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.FrequencyHzEditField.Limits = [1 1000000];
            app.FrequencyHzEditField.Value = 1000;
            app.SoundLeveldBSPLEditFieldLabel = uilabel(app.ReferencePanel);
            app.SoundLeveldBSPLEditFieldLabel.Text = 'Sound Level (dB SPL)';
            app.SoundLeveldBSPLEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.SoundLeveldBSPLEditField.Limits = [1 1000000];
            app.SoundLeveldBSPLEditField.Value = 114;
            app.MeasuredVoltagemVEditFieldLabel = uilabel(app.ReferencePanel);
            app.MeasuredVoltagemVEditFieldLabel.Text = 'Measured Voltage (mV)';
            app.MeasuredVoltagemVEditField = uieditfield(app.ReferencePanel, 'numeric');
            app.MeasuredVoltagemVEditField.LowerLimitInclusive = 'off';
            app.MeasuredVoltagemVEditField.Limits = [0 1000000000];
            app.MeasuredVoltagemVEditField.Value = 100;
            app.SampleButton = uibutton(app.ReferencePanel, 'push');
            app.SampleButton.ButtonPushedFcn = createCallbackFcn(app, @SampleButtonPushed, true);
            app.SampleButton.Text = 'Sample';
            app.ReferenceLamp = uilamp(app.ReferencePanel);
            app.ReferenceInfoButton = uibutton(app.ReferencePanel, 'push');
            app.ReferenceInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceInfoButtonPushed, true);
            app.ReferenceInfoButton.Icon = 'helpicon.gif';
            app.ReferenceInfoButton.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Calibration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationFigure)
        end
    end
end