classdef Schedule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScheduleFigure               matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadScheduleMenu             matlab.ui.container.Menu
        SaveScheduleMenu             matlab.ui.container.Menu
        ScheduleMenu                 matlab.ui.container.Menu
        SortbyHighlightedColumnMenu  matlab.ui.container.Menu
        RemoveUnselectedRowsMenu     matlab.ui.container.Menu
        ResetScheduleMenu            matlab.ui.container.Menu
        DeselectAllRowsMenu          matlab.ui.container.Menu
        SelectEveryOtherRowMenu      matlab.ui.container.Menu
        CustomSelectionMenu          matlab.ui.container.Menu
        ToggleCurrentSelectionMenu   matlab.ui.container.Menu
        ScheduleTable                matlab.ui.control.Table
        ButtonPanel                  matlab.ui.container.Panel
        SortonColumnButton           matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        DeselectAllButton            matlab.ui.control.Button
        EveryOtherButton             matlab.ui.control.Button
        CustomButton                 matlab.ui.control.Button
        ToggleButton                 matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        LaunchSchDesGUIButton        matlab.ui.control.Button
        ScheduleInfoButton           matlab.ui.control.Button
        RemoveUnselectedButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        filename (1,:) char
        
        DO_NOT_DELETE (1,1) logical = false;
    end
    
    
    properties (SetAccess = private)
        SIG
        data
        compiled
        props
        
        scheduleDesignFilename (1,:) char
        
    end
    
    properties (SetAccess = private, Dependent)
        selectedData % rows currently selected (checked) in table
        sigArray
    end


    methods
        
        function a = get.sigArray(app)
            d = app.ScheduleTable.Data;
            a = repmat(abr.sigdef.sigs.(app.SIG.Type),size(d,1),1);
            for i = 1:length(app.props)
                for j = 1:size(d,1)
                    a(j).(app.props{i}).Value = d{j,i+1};
                end
            end
        end
        
        function s = get.selectedData(app)
            s = [];
            d = app.ScheduleTable.Data;
            if isempty(d), return; end
            s = [d{:,1}];
        end
        
        function p = get.props(app)
            if isempty(app.props)
                p = fieldnames(app.data);
            else
                p = app.props; % may have been rearanged during compile
            end
        end
        
        
        function preprocess(app,SIG,props,data)
            
            app.SIG = SIG;
            
            for i = 1:length(props)
                ind = ismember(data(:,1),SIG.(props{i}).DescriptionWithUnit);
                if ~any(ind), continue; end
                
                P = app.SIG.(props{i});
                
                D.(props{i}).SIG = P; % store sigProp for later
                
                D.(props{i}).Alternate   = data{ind,2};
                D.(props{i}).Description = P.Description;
                
                switch P.Type
                    case 'String'
                        D.(props{i}).values = data{ind,3};
                        D.(props{i}).N = 1;
                        D.(props{i}).type = 'string';
                        
                    case 'File'
                        % THIS IS NOT RIGHT
                        f = cellfun(@(a) a(find(a==filesep,1,'last')+1:find(a=='.',1,'last')-1),P.Value,'uni',0);
                        D.(props{i}).values = f;
                        D.(props{i}).N = numel(P.Value);
                        D.(props{i}).filenames = P.Value;
                        D.(props{i}).type = 'files';
                        
                    case 'Numeric'
                        Selected = data{ind,3};
                        if Selected(1) ~= '[', Selected = ['[' Selected ']']; end %#ok<AGROW>
                        
                        if data{ind,2}
                            D.(props{i}).values = Selected;
                            D.(props{i}).N = 1;
                            D.(props{i}).type = 'Numeric';
                            D.(props{i}).Alternate = 1;
                        else
                            D.(props{i}).values = eval(Selected);
                            D.(props{i}).N = numel(D.(props{i}).values);
                            D.(props{i}).type = 'Numeric';
                            D.(props{i}).Alternate = 0;
                        end
                end
            end
            
            
            app.data = D;
        end
        
        function compile(app)
            D  = app.data;
            Ps = app.props;
            D = orderfields(D,Ps);
            
            C = {};
            
            N = structfun(@(a) a.N,D);
            
            k = 1;
            
            for i = 1:length(Ps)

                vj = D.(Ps{i}).values;
                
                if D.(Ps{i}).Alternate
                    if ~iscell(vj), vj = {vj}; end
                else
                    if ischar(vj)
                        vj = {vj};
                    else
                        if ~iscell(vj), vj = num2cell(vj); end
                    end
                end
                
                if isempty(C)
                    C(:,k) = vj(:);
                else
                    if N(i) > 1
                        C = [C; repmat(C,N(i)-1,1)];
                        vje = repmat(vj,size(C,1)/N(i),1);
                    else
                        vje = repmat(vj,size(C,1),1);
                    end
                    if isempty(vje)
                        C(:,k) = {nan};
                    else
                        C(:,k) = vje(:);
                    end
                end
                k = k + 1;
            end
            
            % sort columns of C by number of unique values in descending order
            [~,i] = sort(N,'descend');
            app.compiled = C(:,i);
            app.props    = Ps(i);
            app.data     = D; % fields may have been removed
        end
        
        function update(app)
            
            n = length(app.props);
            
            t = table;
            t.Selected = true(size(app.compiled,1),1);
            alias = cell(1,n);
            for i = 1:n
                if isnumeric(app.compiled{1,i}) || islogical(app.compiled{1,i})
                    t.(app.props{i}) = [app.compiled{:,i}]';
                else
                    t.(app.props{i}) = app.compiled(:,i);
                end
                alias{i} = app.SIG.(app.props{i}).AliasWithUnit;
                if isempty(alias{i}), alias{i} = app.props{i}; end
            end
            t.Properties.VariableDescriptions = [{'Selected'} alias];
            
            
            app.ScheduleTable.ColumnName     = t.Properties.VariableDescriptions;
            app.ScheduleTable.ColumnWidth    = [{20} repmat({'auto'},1,n)];
            app.ScheduleTable.ColumnEditable = [true, false(1,n)];
            app.ScheduleTable.RowStriping    = 'on';
            app.ScheduleTable.Data = t;
            
            app.ScheduleTable.UserData.Table = t;
            app.ScheduleTable.UserData.Obj = app;
            
            
            [pn,fn,~] = fileparts(app.filename);
            if isempty(pn)
                app.ScheduleFigure.Name = 'Schedule: *NEW SCHEDULE*';
                app.update_save_state('on');
            else
                app.ScheduleFigure.Name = sprintf('Schedule: %s [%s]',fn,pn);
                app.update_save_state('off');
            end
            
            figure(app.ScheduleFigure);
        end
        
        function update_highlight(app,row)
            n = size(app.ScheduleTable.Data,1);
            r = cellfun(@num2str,num2cell((1:n)'),'uni',0);
            r{row} = sprintf('%d >>',row);
            app.ScheduleTable.RowName = r;
            
            % doesn't work in uifigure
            %             persistent jUIScrollPane
            %
            %             if isempty(jUIScrollPane)
            %                 jUIScrollPane = findjobj(app.ScheduleTable);
            %             end
            %             jUITable = jUIScrollPane.getViewport.getView;
            %             jUITable.changeSelection(row-1,0, false, false);
            %             jUITable.changeSelection(row-1,size(app.ScheduleTable.Data,2)-1,false,true);
            %
            %             jUIScrollPane.getVerticalScrollBar.setValue(row-1);
            %             jUIScrollPane.getHorizontalScrollBar.setValue(0);
        end
        
        function load_schedule(app,ffn)
            if nargin == 2 && ~isempty(ffn) && ischar(ffn)
                assert(exist(ffn,'file') == 2,'File does not exist! "%s"',ffn)
            else
                dfltpth = getpref('Schedule','path',cd);
                
                [fn,pn] = uigetfile({'*.sch','Schedule (*.sch)'},'Load a file',dfltpth);
                
                if isequal(fn,0), return; end
                
                ffn = fullfile(pn,fn);
                
                setpref('Schedule','path',pn);
            end
            
            fprintf('Loading schedule "%s" ...',ffn)
            
            load(ffn,'-mat','compiled','data','SIG','tblData');
            
            if ~exist('SIG','var') || ~exist('compiled','var') || ~exist('data','var') || ~exist('tblData','var')
                errordlg(sprintf('Essential component(s) missing from schedule file!\n\n%s',ffn),'Schedule','modal');
                return
            end
            
            
            app.SIG      = SIG;
            app.compiled = compiled;
            app.data     = data;
            
            app.filename = ffn;
            
            app.update;
            app.ScheduleTable.Data = tblData;
            
            
            app.update_save_state('off');
            
            
            fprintf(' done\n')
            
        end
    end
    
    methods (Access = private)
        function selection_processor(app,event)
            
            hObj = event.Source;
            
            [M,N] = size(app.ScheduleTable.Data);
            
            if M == 0, return; end % no data yet
            
            switch hObj.Text
                case {'Deselect All','Deselect All Rows'}
                    app.ScheduleTable.Data(:,1) = num2cell(false(M,1));
                    app.DeselectAllButton.Text = 'Select All';
                    app.DeselectAllRowsMenu.Text = 'Select All Rows';
                    
                case {'Select All','Select All Rows'}
                    app.ScheduleTable.Data(:,1) = num2cell(true(M,1));
                    app.DeselectAllButton.Text = 'Deselect All';
                    app.DeselectAllRowsMenu.Text = 'Deselect All Rows';
                    
                case {'Every Other','Select Every Other Row'}
                    Selected = [true(1,M); false(1,M)];
                    app.ScheduleTable.Data(:,1) = num2cell(Selected(1:M))';
                    
                case {'Custom','Custom Selection'}
                    dflt = getpref('Schedule','SelectCustom','1:2:end');
                    
                    opts.Resize='on';
                    opts.WindowStyle='modal';
                    opts.Interpreter='tex';
                    opts.InputFontSize = 14;
                    
                    r = inputdlg('\fontsize{14} Enter expression. M = length of schedule.', ...
                        'Select',1,{dflt},opts);
                    
                    if isempty(r), return; end
                    
                    t = false(M,1);
                    try
                        eval(sprintf('t(%s) = true;',char(r)));
                        app.ScheduleTable.Data(:,1) = num2cell(t);
                        setpref('Schedule','SelectCustom',char(r));
                    catch me
                        errordlg(sprintf('Invalid expression: %s.\n\n%s\n%s', ...
                            char(r),me.identifier,me.message),'Select');
                    end
                    
                case {'Toggle','Toggle Current Selection'}
                    app.ScheduleTable.Data(:,1) = num2cell(~[app.ScheduleTable.Data{:,1}]')';
                    
                case 'Sort on Column'
                    col = app.ScheduleTable.UserData.ColumnSelected;
                    if isempty(col), return; end
                    
                    tbl = app.ScheduleTable.UserData.Table;
                    p = tbl.Properties.VariableNames;
                    sortDir = repmat({'ascend'},1,length(col));
                    for i = 1:length(col)
                        if iscellstr(tbl.(p{col(i)})) %#ok<ISCLSTR>
                            if issorted(string(tbl.(p{col(i)})),sortDir{i}), sortDir{i} = 'descend'; end
                        else
                            if issortedrows(tbl,p(col(i)),sortDir{i}), sortDir{i} = 'descend'; end
                        end
                    end
                    tbl = sortrows(tbl,p(col),sortDir);
                    app.ScheduleTable.UserData.Table = tbl;
                    app.ScheduleTable.Data = tbl;
                    
                    
                case {'Remove Unselected','Remove Unselected Rows'}
                    ind = ~app.ScheduleTable.Data.Selected;
                    if ~any(ind)
                        fprintf(2,'All rows are currently selected. Nothing to remove.\n')
                        return
                    end
                    
                    selection = uiconfirm(app.ScheduleFigure, ...
                        'Remove Unselected Rows?','Schedule', ...
                        'Options',{'Do it','Nevermind'},'DefaultOption',2,'CancelOption',2,...
                        'Icon','question');
                    if isequal(selection,'Nevermind'), return; end
                    
                    app.ScheduleTable.Data(ind,:) = [];
                    
                case {'Reset','Reset Schedule'}
                    app.ScheduleTable.UserData.Obj.update;
            end
            
            if any(app.ScheduleTable.Data.Selected) % table format
                app.update_save_state('on');
            else
                app.update_save_state('off');
            end
            
        end


        function save_schedule(app)
            dfltpth = getpref('Schedule','path',cd);
            
            [fn,pn] = uiputfile({'*.sch','Schedule (*.sch)'},'Save schedule',dfltpth);
            
            if isequal(fn,0), return; end
            
            tblData  = app.ScheduleTable.Data;
            data     = app.data;
            compiled = app.compiled;
            SIG      = app.SIG;
            
            save(fullfile(pn,fn),'-mat','compiled','data','SIG','tblData');
            
            fprintf('Schedule saved as ... %s\n',fullfile(pn,fn))
            
            app.filename = fullfile(pn,fn);
            [~,fn,~] = fileparts(app.filename);
            app.ScheduleFigure.Name = sprintf('Schedule: %s (%s)',fn,pn);
            
            setpref('Scehdule','path',pn);
            
            app.update_save_state('off');
            
            figure(app.ScheduleFigure); % not sure why, but GUI disappears
        end
        
        function update_save_state(app,s)
            
            app.SaveButton.Enable = s;
            app.SaveScheduleMenu.Enable = s;
            
            if isequal(s,'on')
                app.SaveButton.Tooltip = 'Schedule has been altered';
                app.SaveScheduleMenu.Tooltip = 'Schedule has been altered';
            else
                app.SaveButton.Tooltip = 'Schedule has been saved';
                app.SaveScheduleMenu.Tooltip = 'Schedule has been saved';
                
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filename)
            app.ScheduleTable.RowName = 'numbered';
            
            if nargin == 2 && ~isempty(filename)
                app.filename = filename;
                app.load_schedule(filename);
            end
        end

        % Cell selection callback: ScheduleTable
        function ScheduleTableCellSelection(app, event)
            hObj = event.Source;
            if isempty(event.Indices)
                hObj.UserData.ColumnSelected = [];
                hObj.UserData.RowSelected = [];
                return
            end
            row = event.Indices(:,1);
            col = event.Indices(:,2);
            hObj.UserData.ColumnSelected = col;
            hObj.UserData.RowSelected = row;
        end

        % Cell edit callback: ScheduleTable
        function ScheduleTableCellEdit(app, event)
            if any(app.ScheduleTable.Data.Selected)
                app.update_save_state('on');
            else
                app.update_save_state('off');
            end
        end

        % Callback function: LoadButton, LoadScheduleMenu
        function LoadButtonPushed(app, event)
            app.load_schedule;
        end

        % Callback function: SaveButton, SaveScheduleMenu
        function SaveButtonPushed(app, event)
            app.save_schedule;
        end

        % Button pushed function: LaunchSchDesGUIButton
        function LaunchSchDesGUIButtonPushed(app, event)
            ScheduleDesign;
        end

        % Callback function: SortbyHighlightedColumnMenu, 
        % SortonColumnButton
        function SortonColumnButtonPushed(app, event)
            % AppDesigner f's up custom function name when selecting as callback for multiple objects
            app.selection_processor(event);
        end

        % Callback function: DeselectAllButton, DeselectAllRowsMenu
        function DeselectAllButtonPushed(app, event)
            % AppDesigner f's up custom function name when selecting as callback for multiple objects
            app.selection_processor(event);
        end

        % Callback function: EveryOtherButton, SelectEveryOtherRowMenu
        function EveryOtherButtonPushed(app, event)
            % AppDesigner f's up custom function name when selecting as callback for multiple objects
            app.selection_processor(event);
        end

        % Callback function: CustomButton, CustomSelectionMenu
        function CustomButtonPushed(app, event)
            % AppDesigner f's up custom function name when selecting as callback for multiple objects
            app.selection_processor(event);
        end

        % Callback function: ToggleButton, ToggleCurrentSelectionMenu
        function ToggleButtonPushed(app, event)
            % AppDesigner f's up custom function name when selecting as callback for multiple objects
            app.selection_processor(event);
        end

        % Callback function: ResetButton, ResetScheduleMenu
        function ResetButtonPushed(app, event)
            % AppDesigner f's up custom function name when selecting as callback for multiple objects
            app.selection_processor(event);
        end

        % Close request function: ScheduleFigure
        function ScheduleFigureCloseRequest(app, event)
            if app.DO_NOT_DELETE
                uialert(app.ScheduleFigure,'I''m sorry Dave, I can''t do that.','Schedule', ...
                    'Icon','warning','Modal',true);
                return
            end
            
            if isequal(app.SaveButton.Enable,'on')
                r = uiconfirm(app.ScheduleFigure, ...
                    'Would you like to save changes to the current Schedule before exiting?', ...
                    'Schedule','Options',{'Don''t Save','Cancel','Save'},'DefaultOption','Save', ...
                    'CancelOption','Cancel');
                switch r
                    case 'Save'
                        app.save_schedule;
                    case 'Cancel'
                        return;
                end
            end
            delete(app)
            
        end

        % Callback function: RemoveUnselectedButton, 
        % RemoveUnselectedRowsMenu
        function RemoveUnselectedRowsMenuSelected(app, event)
             % AppDesigner f's up custom function name when selecting as callback for multiple objects
            app.selection_processor(event);
        end

        % Button pushed function: ScheduleInfoButton
        function ScheduleInfoButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScheduleFigure = uifigure;
            app.ScheduleFigure.Name = 'Schedule';
            app.ScheduleFigure.CloseRequestFcn = createCallbackFcn(app, @ScheduleFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ScheduleFigure);
            app.FileMenu.Text = 'File';
            app.LoadScheduleMenu = uimenu(app.FileMenu);
            app.LoadScheduleMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadScheduleMenu.Accelerator = 'L';
            app.LoadScheduleMenu.Text = 'Load Schedule';
            app.SaveScheduleMenu = uimenu(app.FileMenu);
            app.SaveScheduleMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveScheduleMenu.Accelerator = 'S';
            app.SaveScheduleMenu.Text = 'Save Schedule';
            app.ScheduleMenu = uimenu(app.ScheduleFigure);
            app.ScheduleMenu.Text = 'Schedule';
            app.SortbyHighlightedColumnMenu = uimenu(app.ScheduleMenu);
            app.SortbyHighlightedColumnMenu.MenuSelectedFcn = createCallbackFcn(app, @SortonColumnButtonPushed, true);
            app.SortbyHighlightedColumnMenu.Accelerator = 'C';
            app.SortbyHighlightedColumnMenu.Text = 'Sort by Highlighted Column';
            app.RemoveUnselectedRowsMenu = uimenu(app.ScheduleMenu);
            app.RemoveUnselectedRowsMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveUnselectedRowsMenuSelected, true);
            app.RemoveUnselectedRowsMenu.Separator = 'on';
            app.RemoveUnselectedRowsMenu.Accelerator = 'U';
            app.RemoveUnselectedRowsMenu.Text = 'Remove Unselected Rows';
            app.ResetScheduleMenu = uimenu(app.ScheduleMenu);
            app.ResetScheduleMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetScheduleMenu.Accelerator = 'R';
            app.ResetScheduleMenu.Text = 'Reset Schedule';
            app.DeselectAllRowsMenu = uimenu(app.ScheduleMenu);
            app.DeselectAllRowsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllRowsMenu.Separator = 'on';
            app.DeselectAllRowsMenu.Accelerator = 'D';
            app.DeselectAllRowsMenu.Text = 'Deselect All Rows';
            app.SelectEveryOtherRowMenu = uimenu(app.ScheduleMenu);
            app.SelectEveryOtherRowMenu.MenuSelectedFcn = createCallbackFcn(app, @EveryOtherButtonPushed, true);
            app.SelectEveryOtherRowMenu.Accelerator = 'O';
            app.SelectEveryOtherRowMenu.Text = 'Select Every Other Row';
            app.CustomSelectionMenu = uimenu(app.ScheduleMenu);
            app.CustomSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomButtonPushed, true);
            app.CustomSelectionMenu.Accelerator = 'M';
            app.CustomSelectionMenu.Text = 'Custom Selection';
            app.ToggleCurrentSelectionMenu = uimenu(app.ScheduleMenu);
            app.ToggleCurrentSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleButtonPushed, true);
            app.ToggleCurrentSelectionMenu.Accelerator = 'T';
            app.ToggleCurrentSelectionMenu.Text = 'Toggle Current Selection';
            app.ScheduleTable = uitable(app.ScheduleFigure);
            app.ScheduleTable.ColumnName = {''};
            app.ScheduleTable.RowName = {};
            app.ScheduleTable.ColumnEditable = false;
            app.ScheduleTable.CellEditCallback = createCallbackFcn(app, @ScheduleTableCellEdit, true);
            app.ScheduleTable.CellSelectionCallback = createCallbackFcn(app, @ScheduleTableCellSelection, true);
            app.ButtonPanel = uipanel(app.ScheduleFigure);
            app.SortonColumnButton = uibutton(app.ButtonPanel, 'push');
            app.SortonColumnButton.ButtonPushedFcn = createCallbackFcn(app, @SortonColumnButtonPushed, true);
            app.SortonColumnButton.Text = 'Sort on Column';
            app.ResetButton = uibutton(app.ButtonPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DeselectAllButton = uibutton(app.ButtonPanel, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.EveryOtherButton = uibutton(app.ButtonPanel, 'push');
            app.EveryOtherButton.ButtonPushedFcn = createCallbackFcn(app, @EveryOtherButtonPushed, true);
            app.EveryOtherButton.Text = 'Every Other';
            app.CustomButton = uibutton(app.ButtonPanel, 'push');
            app.CustomButton.ButtonPushedFcn = createCallbackFcn(app, @CustomButtonPushed, true);
            app.CustomButton.Text = 'Custom';
            app.ToggleButton = uibutton(app.ButtonPanel, 'push');
            app.ToggleButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleButtonPushed, true);
            app.ToggleButton.Text = 'Toggle';
            app.LoadButton = uibutton(app.ButtonPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'file_open.png';
            app.LoadButton.Text = '';
            app.SaveButton = uibutton(app.ButtonPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'file_save.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '';
            app.LaunchSchDesGUIButton = uibutton(app.ButtonPanel, 'push');
            app.LaunchSchDesGUIButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchSchDesGUIButtonPushed, true);
            app.LaunchSchDesGUIButton.Icon = 'guideicon.gif';
            app.LaunchSchDesGUIButton.Text = '';
            app.ScheduleInfoButton = uibutton(app.ButtonPanel, 'push');
            app.ScheduleInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ScheduleInfoButtonPushed, true);
            app.ScheduleInfoButton.Icon = 'helpicon.gif';
            app.ScheduleInfoButton.Text = '';
            app.RemoveUnselectedButton = uibutton(app.ButtonPanel, 'push');
            app.RemoveUnselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveUnselectedRowsMenuSelected, true);
            app.RemoveUnselectedButton.Text = 'Remove Unselected';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Schedule(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScheduleFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScheduleFigure)
        end
    end
end