classdef ScheduleDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScheduleDesignFigure     matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        SaveScheduleDesignMenu   matlab.ui.container.Menu
        LoadScheduleDesignMenu   matlab.ui.container.Menu
        SigDefTable              matlab.ui.control.Table
        SignalTypeDropDownLabel  matlab.ui.control.Label
        SignalTypeDropDown       matlab.ui.control.DropDown
        CompileButton            matlab.ui.control.Button
        PlotButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        axSigPlot
        ScheduleDesignFigureSigPlot
    end
    
    properties (SetAccess = private, GetAccess = public)
        SCH % abr.sigdef.Schedule
        SIG % abr.sigdef.sigs....
        SigFiles
    end
    
    methods (Access = public)
        
        function updateSIG(app)
            
            props = app.SigDefTable.UserData;
            data  = app.SigDefTable.Data;
            
            for i = 1:length(props)
                app.SIG.(props{i}).Alternate = data{i,2};
                app.SIG.(props{i}).Value     = data{i,3};
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SignalTypeDropDownValueChanged;
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            sigType = app.SignalTypeDropDown.Value;
            
            app.SIG = abr.sigdef.sigs.(sigType);
            
            props = properties(app.SIG);
            
            ind = cellfun(@(a) isa(app.SIG.(a),'abr.sigdef.sigProp'),props);
            props(~ind) = [];
            
            ind = cellfun(@(a) app.SIG.(a).Active,props);
            props(~ind) = [];
            
            app.SigFiles = [];
            app.SigDefTable.Data = {[]};
            for i = 1:length(props)
                
                if ~isa(app.SIG.(props{i}),'abr.sigdef.sigProp') || ~app.SIG.(props{i}).Active, continue; end
                
                descr = app.SIG.(props{i}).DescriptionWithUnit;
                if isempty(descr), continue; end % only include properties with descriptions
                
                app.SigDefTable.Data{end+1,1} = descr;
                v = app.SIG.(props{i}).Value;
                
                if isnumeric(v), v = mat2str(v); end
                
                app.SigDefTable.Data{end,3} = v;
                
                % Alternating parameter?
                app.SigDefTable.Data{end,2} = app.SIG.(props{i}).Alternate;
            end
            app.SigDefTable.Data(1,:) = [];
            
            app.SigDefTable.UserData = props;
        end

        % Cell selection callback: SigDefTable
        function SigDefTableCellSelection(app, event)
            if isempty(event.Indices), return; end
            
            row = event.Indices(1);
            col = event.Indices(2);
            
            prop = app.SigDefTable.UserData{row};
            
            switch col
                case 1 % NAME
                case 2 % ALTERNATE
                case 3 % VALUE
                    
                    if ~isempty(app.SIG.(prop).Function)
                        % call a function
                        result = feval(app.SIG.(prop).Function,app.SIG);
                        
                        if isequal(result,'NOVALUE'), return; end
                        
                        app.SIG.(prop).Value = result;
                        
                        switch app.SIG.(prop).Type
                            case 'File'
                                f = cellfun(@(a) a(find(a==filesep,1,'last')+1:find(a=='.',1,'last')-1),result,'uni',0);
                                app.SigDefTable.Data{row,col} = sprintf('"%s" ',f{:});
                                
                            case 'String'
                                app.SigDefTable.Data{row,col} = result;
                        end
                    end
            end
        end

        % Cell edit callback: SigDefTable
        function SigDefTableCellEdit(app, event)
            
            if isempty(event.Indices), return; end
            
            row = event.Indices(1);
            col = event.Indices(2);
            
            v = app.SigDefTable.Data{row,3};
            
            if v(1) ~= '[', v = ['[' v ']']; end
            
            try
                nd = eval(v);
                
            catch me
                switch me.identifier
                    case 'MATLAB:UndefinedFunction'
                        app.SigDefTable.Data{row,col} = event.PreviousData;
                        helpdlg(sprintf('Unable to evaluate: "%s"',event.NewData));
                        return
                        
                    otherwise
                        rethrow(me)
                end
                
            end
            
            switch col
                case 1 % NAME
                case 2 % ALTERNATE
                    
                    if app.SigDefTable.Data{row,col} == true && numel(nd) ~= 2
                        helpdlg('Must have two values to use Alternate option.');
                        app.SigDefTable.Data{row,col} = false;
                    end
                    
                case 3 % VALUE
                    if numel(nd) ~= 2
                        app.SigDefTable.Data{row,2} = false;
                    end
                    
                    try
                        app.updateSIG;
                        app.SIG = app.SIG.update;
                        
                    catch me
                        errordlg(sprintf('Invalid expression:\n\n%s\n\n%s', ...
                            me.identifier,me.message),'Schedule Design','modal');
                        app.SigDefTable.Data{row,3} = event.PreviousData;
                    end
                    
            end
            
            app.updateSIG;
            app.SIG = app.SIG.update;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if isempty(app.ScheduleDesignFigureSigPlot)
                app.ScheduleDesignFigureSigPlot = figure('name','SigPlot','color','w');
                app.axSigPlot  = axes(app.ScheduleDesignFigureSigPlot,'Tag','SigPlot');
            end
            
            app.SIG = app.SIG.update;
            
            figure(app.ScheduleDesignFigureSigPlot);
            
            %         btnSigSched_Callback([],[],true);
            cla(app.axSigPlot);
            grid(app.axSigPlot,'on');
            
            app.axSigPlot.XAxis.Label.String = 'time (ms)';
            app.axSigPlot.YAxis.Label.String = 'amplitude';
            for k = 1:numel(app.SIG.data)
                if length(app.SIG.timeVector) == 1
                    t = app.SIG.timeVector{1};
                else
                    t = app.SIG.timeVector{k};
                end
                hl(k) = line(app.axSigPlot,t*1000,app.SIG.data{k},'linestyle','-', ...
                    'marker','o','markersize',4,'linewidth',2);
                if numel(hl) > 1
                    set(hl(1:end-1),'marker','none','color',[0.8 0.8 0.8],'linewidth',1);
                end
                
                %             y = max(abs(app.SIG.data{k}(:))) * 1.1;
                %             app.axSigPlot.YAxis.Limits = [-y y];
                app.axSigPlot.YAxis.Limits = [-1.1 1.1];
                
                if isa(app.SIG,'abr.sigdef.sigs.File')
                    fn = app.SIG.fullFilename.Value{k};
                    fn = fn(find(fn==filesep,1,'last')+1:find(fn=='.',1,'last')-1);
                    pstr = sprintf('Filename: "%s"',fn);
                else
                    x = app.SIG.dataParams;
                    N = structfun(@(a) numel(unique(a)),x);
                    p = fieldnames(x);
                    x = rmfield(x,p(N==1));
                    
                    pstr = '';
                    for i = fieldnames(x)'
                        v = x.(char(i));
                        pstr = sprintf('%s| %s: %.2f ',pstr,app.SIG.(char(i)).Alias,v(k));
                    end
                    pstr([1:2 end]) = [];
                end
                app.axSigPlot.Title.String = pstr;
                
                pause(0.2)
            end
            
            
        end

        % Button pushed function: CompileButton
        function CompileButtonPushed(app, event)
            
            props = app.SigDefTable.UserData;
            data  = app.SigDefTable.Data;
            
            if ~isfield(app,'SCH') || ~isa(app.SCH,'abr.sigdef.Schedule')
                app.SCH = abr.sigdef.Schedule;
            end
            
            if isempty(app.SCH.schFig)
                app.SCH.createGUI;
            end
            
            app.SCH.preprocess(app.SIG,props,data);
            app.SCH.compile;
            app.SCH.update;
            
            if nargin < 3 || ~ignoreFigure
                figure(app.SCH.schFig);
            end
            
            app.CompileButton.UserData = rand(1); % ... just keep this here
            
        end

        % Window button down function: ScheduleDesignFigure
        function ScheduleDesignFigureWindowButtonDown(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScheduleDesignFigure = uifigure;
            app.ScheduleDesignFigure.Name = 'UI Figure';
            app.ScheduleDesignFigure.WindowButtonDownFcn = createCallbackFcn(app, @ScheduleDesignFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.ScheduleDesignFigure);
            app.FileMenu.Text = 'File';
            app.SaveScheduleDesignMenu = uimenu(app.FileMenu);
            app.SaveScheduleDesignMenu.Accelerator = 'S';
            app.SaveScheduleDesignMenu.Text = 'Save Schedule Design';
            app.LoadScheduleDesignMenu = uimenu(app.FileMenu);
            app.LoadScheduleDesignMenu.Accelerator = 'L';
            app.LoadScheduleDesignMenu.Text = 'Load Schedule Design';
            app.SigDefTable = uitable(app.ScheduleDesignFigure);
            app.SigDefTable.ColumnName = {'Property'; 'Alternate'; 'Value/Expression'};
            app.SigDefTable.RowName = {};
            app.SigDefTable.ColumnEditable = [false true true];
            app.SigDefTable.CellEditCallback = createCallbackFcn(app, @SigDefTableCellEdit, true);
            app.SigDefTable.CellSelectionCallback = createCallbackFcn(app, @SigDefTableCellSelection, true);
            app.SignalTypeDropDownLabel = uilabel(app.ScheduleDesignFigure);
            app.SignalTypeDropDownLabel.Text = 'Signal Type:';
            app.SignalTypeDropDown = uidropdown(app.ScheduleDesignFigure);
            app.SignalTypeDropDown.Items = {'Tone', 'Noise', 'Click', 'File'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Tone';
            app.CompileButton = uibutton(app.ScheduleDesignFigure, 'push');
            app.CompileButton.ButtonPushedFcn = createCallbackFcn(app, @CompileButtonPushed, true);
            app.CompileButton.Text = 'Compile';
            app.PlotButton = uibutton(app.ScheduleDesignFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ScheduleDesign

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScheduleDesignFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScheduleDesignFigure)
        end
    end
end